classdef Trajectory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AngleSlider               matlab.ui.control.Slider
        AngleSliderLabel          matlab.ui.control.Label
        VelocitySlider            matlab.ui.control.Slider
        VelocitySliderLabel       matlab.ui.control.Label
        angleEditField            matlab.ui.control.NumericEditField
        angleEditFieldLabel       matlab.ui.control.Label
        velocitymsEditField       matlab.ui.control.NumericEditField
        velocitymsEditFieldLabel  matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function start(app, event)
            [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, app.angleEditField.Value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: velocitymsEditField
        function change_vel(app, event)
            value = app.velocitymsEditField.Value;
            [x, y, x1, y1, x2, y2] = missle_trajectory(value, app.angleEditField.Value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: angleEditField
        function change_angle(app, event)
            value = app.angleEditField.Value;
            [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: AngleSlider
        function Angle_change_slider(app, event)
            value = app.AngleSlider.Value;
            [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, value);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changing function: AngleSlider
        function Angle_changing_slider(app, event)
            changingValue = event.Value;
            [x, y, x1, y1, x2, y2] = missle_trajectory(app.velocitymsEditField.Value, changingValue);
            plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changed function: VelocitySlider
        function value_changed(app, event)
            value = app.VelocitySlider.Value;
            [x, y, x1, y1, x2, y2] = missle_trajectory(value, app.angleEditField.Value);
             plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end

        % Value changing function: VelocitySlider
        function value_vel_changing(app, event)
            changingValue = event.Value;
            [x, y, x1, y1, x2, y2] = missle_trajectory(changingValue, app.angleEditField.Value);
             plot(app.UIAxes, x,y,'-',x1,y1,'-',x2,y2,'-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory of 35 mm missle')
            xlabel(app.UIAxes, 'X range (m)')
            ylabel(app.UIAxes, 'Y range (m)')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Text = 'Start';
            app.velocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.velocitymsEditFieldLabel.Text = 'velocity m/s';
            app.velocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.velocitymsEditField.Limits = [0 2000];
            app.velocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @change_vel, true);
            app.velocitymsEditField.Value = 30;
            app.angleEditFieldLabel = uilabel(app.UIFigure);
            app.angleEditFieldLabel.Text = {'angle'; ''};
            app.angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.angleEditField.Limits = [0 90];
            app.angleEditField.ValueChangedFcn = createCallbackFcn(app, @change_angle, true);
            app.angleEditField.Value = 20;
            app.VelocitySliderLabel = uilabel(app.UIFigure);
            app.VelocitySliderLabel.Text = {'Velocity'; ''};
            app.VelocitySlider = uislider(app.UIFigure);
            app.VelocitySlider.Limits = [0 2000];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @value_changed, true);
            app.VelocitySlider.ValueChangingFcn = createCallbackFcn(app, @value_vel_changing, true);
            app.VelocitySlider.Value = 30;
            app.AngleSliderLabel = uilabel(app.UIFigure);
            app.AngleSliderLabel.Text = {'Angle'; ''};
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [0 180];
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @Angle_change_slider, true);
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @Angle_changing_slider, true);
            app.AngleSlider.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trajectory

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end